classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        LoadDataButton  matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Open a dialog box for the user to select a .csv file.
            % The dialog will only show files with the .csv extension.
            [fileName, filePath] = uigetfile('*.csv', 'Select a CSV file to plot');
            
            % Check if the user clicked "Cancel" on the file dialog.
            % If so, fileName will be 0, and we should exit the function.
            if isequal(fileName, 0)
                return;
            end
            
            % Create the full path to the selected file.
            fullPath = fullfile(filePath, fileName);
            
            try
                % Read the data from the CSV file into a table.
                % Using readtable is great because it can automatically
                % detect and use headers for column names.
                data = readtable(fullPath);
                
                % Check if the table has at least two columns to plot.
                if width(data) >= 2
                    % Get the names of the first two columns from the table headers.
                    x_col_name = data.Properties.VariableNames{1};
                    y_col_name = data.Properties.VariableNames{2};
                    
                    % Extract the data for plotting.
                    x_data = data.(x_col_name);
                    y_data = data.(y_col_name);
                    
                    % Plot the data on the app's UIAxes component.
                    plot(app.UIAxes, x_data, y_data);
                    
                    % Create safe versions of the filename AND column names by escaping underscores.
                    safeFileName = strrep(fileName, '_', ' ');
                    safe_x_col_name = strrep(x_col_name, '_', ' ');
                    safe_y_col_name = strrep(y_col_name, '_', ' ');

                    % Update the axes labels and title using the safe file and column names.
                    % Using 'tex' interpreter allows for special characters like subscripts.
                    xlabel(app.UIAxes, safe_x_col_name, 'Interpreter', 'tex');
                    ylabel(app.UIAxes, safe_y_col_name, 'Interpreter', 'tex');
                    title(app.UIAxes, ['Data from ', safeFileName], 'Interpreter', 'tex');
                else
                    % If the file has fewer than two columns, show an alert to the user.
                    uialert(app.UIFigure, 'Selected file must have at least two columns.', 'Invalid File');
                end
                
            catch ME
                % If any error occurs (e.g., file is not a valid CSV),
                % display an error message in an alert dialog.
                uialert(app.UIFigure, ['Error loading file: ' ME.message], 'Error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end